classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        Button_3          matlab.ui.control.Button
        aEditFieldLabel   matlab.ui.control.Label
        aEditField        matlab.ui.control.NumericEditField
        bEditFieldLabel   matlab.ui.control.Label
        bEditField        matlab.ui.control.NumericEditField
        AutoCheckBox      matlab.ui.control.CheckBox
        DropDownLabel     matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        Button_4          matlab.ui.control.Button
        rEditFieldLabel   matlab.ui.control.Label
        rEditField        matlab.ui.control.NumericEditField
        ButtonGroup       matlab.ui.container.ButtonGroup
        Channel1Button    matlab.ui.control.RadioButton
        Channel2Button    matlab.ui.control.RadioButton
        Channel3Button    matlab.ui.control.RadioButton
        Button            matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_19         matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        Panel_3           matlab.ui.container.Panel
        DFTButton         matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Panel_4           matlab.ui.container.Panel
        DropDown_2Label   matlab.ui.control.Label
        DropDown_2        matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        CheckBox          matlab.ui.control.CheckBox
        Button_8          matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Panel_5           matlab.ui.container.Panel
        Button_12         matlab.ui.control.Button
        Button_13         matlab.ui.control.Button
        Button_14         matlab.ui.control.Button
        Button_15         matlab.ui.control.Button
        Button_16         matlab.ui.control.Button
        QDEditFieldLabel  matlab.ui.control.Label
        QDEditField       matlab.ui.control.NumericEditField
        Button_17         matlab.ui.control.Button
        Button_18         matlab.ui.control.Button
    end

    
    properties (Access = private)
        PresentChannel=1; % Description
        images={}; 
        Imagenumber=0 ;%当前图片数量
        noiseDialog % Description
        kindDialog
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            axis(app.UIAxes,'off');
            axis(app.UIAxes_2,'off');
            axis(app.UIAxes_3,'off');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
             if app.Channel1Button.Value==true
                presentChannel=1;              
            elseif app.Channel2Button.Value==true
                presentChannel=2;
            elseif app.Channel3Button.Value==true
                presentChannel=3;
            end
            app.PresentChannel=presentChannel;                
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
          [filename,pathname]=uigetfile({'*.jpg';'*.png';'*.tif';'*.jpeg'});
          if filename==0
              warndlg('请选择图片','警告');                  
              return
          end
           %首先将上一次读取的图片及其处理全部清空
          app.images(:)=[];
          app.Imagenumber=0;
          image=imread([pathname,filename]);
          app.Imagenumber=app.Imagenumber+1;
          app.images{app.Imagenumber}=image;
           switch app.PresentChannel
              case 1
                imshow(image,'parent',app.UIAxes);
              case 2
                imshow(image,'parent',app.UIAxes_2);  
              case 3
                imshow(image,'parent',app.UIAxes_3);
          end            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.Imagenumber==1
                warndlg('已经是最后一张图片辣');
                return
            end
            %撤销的算法就是删除当前图片，并显示回原来的图片
            app.Imagenumber=app.Imagenumber-1;
            image=cell2mat(app.images(app.Imagenumber));
            switch app.PresentChannel
              case 1
                imshow(image,'parent',app.UIAxes);
              case 2
                imshow(image,'parent',app.UIAxes_2);  
              case 3
                imshow(image,'parent',app.UIAxes_3);
            end    
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            type=app.DropDown.Value;
            im=cell2mat(app.images(app.Imagenumber));
            a=app.aEditField.Value;
            b=app.bEditField.Value;
            r=app.rEditField.Value;
            processed=ImageNoised(im,type,a,b,r);
            app.Imagenumber=app.Imagenumber+1;
            app.images{app.Imagenumber}=processed;
            switch app.PresentChannel
              case 1
                imshow(processed,'parent',app.UIAxes);
              case 2
                imshow(processed,'parent',app.UIAxes_2);  
              case 3
                imshow(processed,'parent',app.UIAxes_3);
            end    
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.noiseDialog=NoiseDialog();
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            if value==true
                app.aEditField.Enable='off';
                app.bEditField.Enable='off';
                app.rEditField.Enable='off';               
            else
                app.aEditField.Enable='on';
                app.bEditField.Enable='on';
                app.cEditField.Enable='on';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
          
            switch app.DropDown.Value
                    case 'uniform'
                        app.aEditField.Value=0;
                        app.bEditField.Value=1;
                        app.rEditField.Value=0.15;
                    case 'gaussian'
                        app.aEditField.Value=0.4;
                        app.bEditField.Value=0.5;
                        app.rEditField.Value=0.2;
                    case 'salt&pepper'
                        app.aEditField.Value=0.15;
                        app.bEditField.Value=0.15;
                        app.rEditField.Value=0;
                        
                    case 'lognormal'
                        app.aEditField.Value=0.5;
                        app.bEditField.Value=0.5;
                        app.rEditField.Value=0.08;
                    case 'exponential'
                        app.aEditField.Value=3;
                        app.bEditField.Value=3;
                        app.rEditField.Value=0.1;
                    case 'rayleigh'
                        app.aEditField.Value=0.1;
                        app.bEditField.Value=3;
                        app.rEditField.Value=0.05;
                    case 'erlang'
                        app.aEditField.Value=4;
                        app.bEditField.Value=2;
                        app.rEditField.Value=0.1;
             end
        end

        % Button pushed function: DFTButton
        function DFTButtonPushed(app, event)
            im=cell2mat(app.images(app.Imagenumber));
            im=DFTshow(im);
            switch app.PresentChannel
              case 1
                imshow(im,[],'parent',app.UIAxes);
              case 2
                imshow(im,[],'parent',app.UIAxes_2);  
              case 3
                imshow(im,[],'parent',app.UIAxes_3);
            end    
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            im=cell2mat(app.images(app.Imagenumber));
            i=imcrop(im);
            [count,x]=imhist(i);
            switch app.PresentChannel
              case 1
                  reset(app.UIAxes);
                  axis(app.UIAxes,'on');
                stem(app.UIAxes,x,count);
              case 2
                  reset(app.UIAxes_2);
                  axis(app.UIAxes_2,'on');
                stem(app.UIAxes_2,x,count);  
              case 3
                  reset(app.UIAxes_3);
                  axis(app.UIAxes_3,'on');
                stem(app.UIAxes_3,x,count);
            end                                              
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.kindDialog=KindDialog();
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %首先确定滤波器尺寸
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'armean');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end                           
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
             if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'midvalue');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end                        
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'gemean');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                QD=app.QDEditField.Value;
                processed=NoiseProcess(im,siz,'rehamean',QD);
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'hamean');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'maximum');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'minimum');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                processed=NoiseProcess(im,siz,'midpoint');
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            if app.CheckBox.Value==true
                siz=app.EditField.Value;
            else
                siz=str2double(app.DropDown_2.Value(1));
            end
            %进行算术均值滤波
                im=cell2mat(app.images(app.Imagenumber));
                QD=app.QDEditField.Value;
                processed=NoiseProcess(im,siz,'alpha',QD);
                app.Imagenumber=app.Imagenumber+1;
                app.images{app.Imagenumber}=processed;
                switch app.PresentChannel
                     case 1
                        imshow(processed,'parent',app.UIAxes);
                     case 2
                        imshow(processed,'parent',app.UIAxes_2);  
                     case 3
                        imshow(processed,'parent',app.UIAxes_3);
                end  
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            [filename,pathname]=uiputfile({'*.jpg';'*.jpeg';'*.tif'},'choose a file');
            if filename==0
                warndlg('请选择保存地址','warning');
                return
            else
               imwrite(cell2mat(app.Imagenumber(app.Imagenumber)),[pathname,filename]);
            end   
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Channel2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Channel3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '非周期噪声';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '噪声表';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel, 'numeric');
            app.bEditField.Value = 1;
            app.AutoCheckBox = uicheckbox(app.Panel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'uniform', 'gaussian', 'salt&pepper', 'lognormal', 'rayleigh', 'exponential', 'erlang'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'uniform';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '加噪';
            app.rEditFieldLabel = uilabel(app.Panel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Panel, 'numeric');
            app.rEditField.Value = 0.15;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '通道';
            app.Channel1Button = uiradiobutton(app.ButtonGroup);
            app.Channel1Button.Text = 'Channel1';
            app.Channel1Button.Value = true;
            app.Channel2Button = uiradiobutton(app.ButtonGroup);
            app.Channel2Button.Text = 'Channel2';
            app.Channel3Button = uiradiobutton(app.ButtonGroup);
            app.Channel3Button.Text = 'Channel3';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '读取图片';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '撤销';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '图片信息';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '保存图像';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '周期噪声';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '分析仪';
            app.DFTButton = uibutton(app.Panel_3, 'push');
            app.DFTButton.ButtonPushedFcn = createCallbackFcn(app, @DFTButtonPushed, true);
            app.DFTButton.Text = 'DFT图';
            app.Button_5 = uibutton(app.Panel_3, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '局部直方图';
            app.Button_6 = uibutton(app.Panel_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '噪声对照图';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '时域滤波器';
            app.DropDown_2Label = uilabel(app.Panel_4);
            app.DropDown_2Label.Text = '滤波器尺寸';
            app.DropDown_2 = uidropdown(app.Panel_4);
            app.DropDown_2.Items = {'3X3', '5X5', '7X7', '9X9'};
            app.DropDown_2.Value = '3X3';
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '自定义';
            app.EditField = uieditfield(app.Panel_4, 'numeric');
            app.EditField.Value = 3;
            app.CheckBox = uicheckbox(app.Panel_4);
            app.CheckBox.Text = '';
            app.Button_8 = uibutton(app.Panel_4, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '算术均值';
            app.Button_9 = uibutton(app.Panel_4, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '几何均值';
            app.Button_10 = uibutton(app.Panel_4, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '谐波均值';
            app.Button_11 = uibutton(app.Panel_4, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '逆谐波均值';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '统计排序滤波器';
            app.Button_12 = uibutton(app.Panel_5, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '中值滤波器';
            app.Button_13 = uibutton(app.Panel_5, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '最大值滤波器';
            app.Button_14 = uibutton(app.Panel_5, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '最小值滤波器';
            app.Button_15 = uibutton(app.Panel_5, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '中点滤波器';
            app.Button_16 = uibutton(app.Panel_5, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '修正阿尔法滤波器';
            app.QDEditFieldLabel = uilabel(app.Panel_5);
            app.QDEditFieldLabel.Text = 'QD';
            app.QDEditField = uieditfield(app.Panel_5, 'numeric');
            app.Button_17 = uibutton(app.Panel_5, 'push');
            app.Button_17.Text = '自适应滤波器';
            app.Button_18 = uibutton(app.Panel_5, 'push');
            app.Button_18.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end